classdef DCIMG2TIFF_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCIMGtoTIFFConverterUIFigure  matlab.ui.Figure
        FlipHorizontalCheckBox        matlab.ui.control.CheckBox
        CurrentDirectory              matlab.ui.control.TextArea
        DirectoryTextAreaLabel        matlab.ui.control.Label
        ChoosefilesButton             matlab.ui.control.Button
        FileListBox                   matlab.ui.control.ListBox
        FilestoconvertLabel           matlab.ui.control.Label
        ConvertButton                 matlab.ui.control.Button
        LogBox                        matlab.ui.control.TextArea
    end

    methods (Access = private)
        function addLogText(app,Text,varargin)
            % Adds text to log on UI window
            % Args:
            % Text = string or char of text to add to log printout
            % varargin{1}: NewLine = optional boolean specifying whether a new line
            % is added in the log.  Default = true
            if nargin>2
                NewLine = varargin{1};
            else
                NewLine = true;
            end
            if NewLine
                TextToAdd = sprintf("> %s",Text);
                app.LogBox.Value = [TextToAdd; app.LogBox.Value];
            else
                app.LogBox.Value{1} = strcat(app.LogBox.Value{1},Text);
            end
        end
        
        function setDirectory(app,FilePath)
            % Set directory to chosen file path
            app.CurrentDirectory.Value = FilePath;
            cd(FilePath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.addLogText("Please select your DCIMG files for conversion to TIFF format.",true);
        end

        % Button pushed function: ChoosefilesButton
        function ChoosefilesButtonPushed(app, event)
            % Open dialog box to select files to convert
            [Files,FilePath] = uigetfile(".dcimg","MultiSelect","on");
            if isa(FilePath,"string") || isa(FilePath,"cell") || isa(FilePath,"char") % If files selected, set path and files list based on selections
            if ~iscell(Files)
                Files = {Files};
            end
            app.FileListBox.Items = Files;
            app.setDirectory(FilePath)
            app.addLogText("Files selected.",true);
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            % Start conversion of selected files
            app.addLogText("Converting files...",true);
            Flip = app.FlipHorizontalCheckBox.Value; % Whether to flip image horizontally (typically needed on Walter lab Prism TIRF 1)
            Files = app.FileListBox.Items;
            Dir = app.CurrentDirectory.Value{1};
            ResultText = 'Finished all files.'; % Text to display on completing conversion
            for n = 1:numel(Files)
                InFileName = strcat(Dir,filesep,Files{n});
                [~, BaseName, ~] = fileparts(InFileName);
                OutFileName = strcat(Dir,filesep,BaseName,'.tif');
                Canceled = dcimg_to_tiff(InFileName,OutFileName,Flip,strjoin(["Progress on file ", num2str(n), " of ", num2str(numel(Files)),"."],''));
                if Canceled
                    ResultText = "Conversion canceled.";
                    break
                else
                app.addLogText(strjoin(["Finished file ", num2str(n), " of ", num2str(numel(Files)),"."],''));
                end
            end
            app.addLogText(ResultText);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCIMGtoTIFFConverterUIFigure = uifigure('Visible', 'off');
            app.DCIMGtoTIFFConverterUIFigure.Name = 'DCIMG to TIFF Converter';
            app.LogBox = uitextarea(app.DCIMGtoTIFFConverterUIFigure);
            app.LogBox.Editable = 'off';
            app.ConvertButton = uibutton(app.DCIMGtoTIFFConverterUIFigure, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.FilestoconvertLabel = uilabel(app.DCIMGtoTIFFConverterUIFigure);
            app.FilestoconvertLabel.Text = 'Files to convert:';
            app.FileListBox = uilistbox(app.DCIMGtoTIFFConverterUIFigure);
            app.FileListBox.Items = {'(none selected)'};
            app.FileListBox.Value = '(none selected)';
            app.ChoosefilesButton = uibutton(app.DCIMGtoTIFFConverterUIFigure, 'push');
            app.ChoosefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefilesButtonPushed, true);
            app.ChoosefilesButton.Text = 'Choose files....';
            app.DirectoryTextAreaLabel = uilabel(app.DCIMGtoTIFFConverterUIFigure);
            app.DirectoryTextAreaLabel.Text = 'Directory:';
            app.CurrentDirectory = uitextarea(app.DCIMGtoTIFFConverterUIFigure);
            app.CurrentDirectory.Editable = 'off';
            app.FlipHorizontalCheckBox = uicheckbox(app.DCIMGtoTIFFConverterUIFigure);
            app.FlipHorizontalCheckBox.Text = 'Flip image horizontally';
            app.FlipHorizontalCheckBox.Value = true;
            % Show the figure after all components are created
            app.DCIMGtoTIFFConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCIMG2TIFF_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCIMGtoTIFFConverterUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCIMGtoTIFFConverterUIFigure)
        end
    end
end